classdef AskMregComponent < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        ChooseMREGcomponentbetween170andpressOKLabel  matlab.ui.control.Label
        OKButton      matlab.ui.control.Button
        Spinner       matlab.ui.control.Spinner
        SpinnerLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
       MainApp %saappi app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, saappi)
            % Store main app in property
            app.MainApp = saappi;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call SAappi public function
            importcomponent(app.MainApp,app.Spinner.Value);
             
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to close the app?', 'Close request');
            if strcmpi(YN,'OK')
                delete(app)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = '';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Limits = [1 70];
            app.Spinner.ValueDisplayFormat = '%.0f';
            app.Spinner.Value = 1;
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ChooseMREGcomponentbetween170andpressOKLabel = uilabel(app.UIFigure);
            app.ChooseMREGcomponentbetween170andpressOKLabel.Text = 'Choose MREG component between 1-70 and press OK.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AskMregComponent(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end