classdef SAappi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ComponentEditField              matlab.ui.control.NumericEditField
        ComponentEditFieldLabel         matlab.ui.control.Label
        SavefiltereddataButton          matlab.ui.control.Button
        SelectfiltertypeButtonGroup     matlab.ui.container.ButtonGroup
        HighpassfilteringButton         matlab.ui.control.RadioButton
        LowpassfilteringButton          matlab.ui.control.RadioButton
        BandpassfilteringButton         matlab.ui.control.RadioButton
        FilterButton                    matlab.ui.control.Button
        LowfrequencyEditField           matlab.ui.control.NumericEditField
        LowfrequencyEditFieldLabel      matlab.ui.control.Label
        HighfrequencyEditField          matlab.ui.control.NumericEditField
        HighfrequencyEditFieldLabel     matlab.ui.control.Label
        CurrentdataEditField            matlab.ui.control.EditField
        CurrentdataEditFieldLabel       matlab.ui.control.Label
        SelectdataButton                matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        Plotmanyonsamedata1ButtonGroup  matlab.ui.container.ButtonGroup
        MoreButton_2                    matlab.ui.control.RadioButton
        FilteredsignalButton_2          matlab.ui.control.RadioButton
        OriginalsignalButton_2          matlab.ui.control.RadioButton
        Plotfordata1ButtonGroup         matlab.ui.container.ButtonGroup
        MoreButton                      matlab.ui.control.RadioButton
        FilteredsignalButton            matlab.ui.control.RadioButton
        OriginalsignalButton            matlab.ui.control.RadioButton
        Plot2Button                     matlab.ui.control.Button
        Plot1Button                     matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        CrossCorreEditField             matlab.ui.control.EditField
        ComponentEditField_3            matlab.ui.control.NumericEditField
        ComponentEditField_3Label       matlab.ui.control.Label
        ComponentEditField_2            matlab.ui.control.NumericEditField
        ComponentEditField_2Label       matlab.ui.control.Label
        Signal2EditField                matlab.ui.control.EditField
        Signal2EditFieldLabel           matlab.ui.control.Label
        Signal1EditField                matlab.ui.control.EditField
        Signal1EditFieldLabel           matlab.ui.control.Label
        PlotpowerspectrumButton         matlab.ui.control.Button
        PlotspectralentropyButton       matlab.ui.control.Button
        CoeffiVarianceEditField         matlab.ui.control.NumericEditField
        CoefficientofvarianceButton     matlab.ui.control.Button
        MaxcrosscorrelationButton       matlab.ui.control.Button
        CorreEditField                  matlab.ui.control.NumericEditField
        CorrelationcoefficientButton    matlab.ui.control.Button
        Import2Button                   matlab.ui.control.Button
        Import1Button                   matlab.ui.control.Button
        Importforsignal2ButtonGroup     matlab.ui.container.ButtonGroup
        FiltereddataButton_2            matlab.ui.control.RadioButton
        CurrentdataButton_2             matlab.ui.control.RadioButton
        Importforsignal1ButtonGroup     matlab.ui.container.ButtonGroup
        FiltereddataButton              matlab.ui.control.RadioButton
        CurrentdataButton               matlab.ui.control.RadioButton
        SignalcalculationsLabel         matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        %measurement_length = length(data)/10; % 1 measurement length in seconds(10hz data), data depends what file you upload
        samplef = 10;                       % samplefrequency: 10 hz data
        samplep = 1/10;                     % sampleperiod 
        file                                % file where is data
        currentdata                         %store the file
        v1                                  %app.HighpassfilteringButton.Value;
        v2                                  %app.LowpassfilteringButton.Value;
        v3                                  %app.BandpassfilteringButton.Value;
        p11                                 %app.OriginalsignalButton.Value;
        p12                                 %app.FilteredsignalButton.Value;
        p13                                 %app.MoreButton.Value;
        p21                                 %app.OriginalsignalButton_2.Value;
        p22                                 %app.FilteredsignalButton_2.Value;
        p23                                 %app.MoreButton_2.Value;
        currentdata1                        %current data button value of importforsignal1buttongroup
        filtereddata1                       %filtered data button value of importforsignal1buttongroup 
        currentdata2                        %current data button value of importforsignal2buttongroup
        filtereddata2                       %filtered data button value of importforsignal2buttongroup
        correcoeffi                         %value of correlation coefficient
        f1                                  %figure obj 
        data                                %data extracted from file
        hpFilteredData                      %high pass filtered data
        lpFilteredData                      %low pass filtered data
        bpFilteredData                      %band pass fitlered data
        FilteredData                        %filterer data
        component                           %the component value which user chooses from mreg or eeg     
        filterbuttonclicked        
        signal1
        signal2    
        AskMregComponentApp                % askmregcomponentapp object
        AskEegComponentApp                 % askeegcomponentapp object
    end
    
  
    methods (Access = public)
        
        function importcomponent(app,value)     %gets mreg/eeg component from another app 
            app.ComponentEditField.Value = value; %stores component value to front view "Component"
            app.component = app.ComponentEditField.Value; %store component value to component variable
        end
    end
    

    methods (Access = private)
      
        function [v, td] = calculateXCORR(app,data1,data2)
            %this function calculates cross correlation between signals, and
            %returns maximum positivve correlation and the time difference
            %which gives the maximum correlation
            %XCORR CALCULATIONS
            [crosscorre,lags] = xcorr(data1,data2);
            %xcorr returns 2*length of vector - 1 lags by default.
            %zero-th lag is datapoint 5820 in lags.
            positivecorre = crosscorre(5820:end);
            positivelags = lags(5820:end);
            %val is the maximum positive correlation
            [v,idx] = max(abs(positivecorre));
            %maxcorreidx is lag index from positive correlations
            maxcorreidx = positivelags(idx);
            %timedifference is the overall time difference which gives
            %maximum correlation IN SECONDS.
            td = maxcorreidx/app.samplef;            
        end
        
        function [freqrange, power] = calcpowerspectrum(app,data)
            %this function calculates the power spectrum of the selected
            %signal using FFT
            fftdata = fft(data);
            %compute power spectrum
            power = 10*log10(abs(fftdata).^2 / length(data)/app.samplef);
            freqrange = linspace(0,app.samplef,length(data));        
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %show all the tooltips of calculation buttons(show what button
            %does when hovering cursor over button)
            app.CorrelationcoefficientButton.Tooltip = "Calculates the correlation coefficient between signal 1 and signal 2";
            app.MaxcrosscorrelationButton.Tooltip = ["Calculates cross correlation between signal 1 and signal 2 and shows maximum positive correlation" ...
                 "and the time difference which gives the maximum correlation (max correlation | time difference)"];
            app.CoefficientofvarianceButton.Tooltip = "Calculates coefficient of variance of signal 1. !YOU CAN ONLY SELECT FULL BAND SIGNAL FOR THIS CALCULATION!";
            app.PlotspectralentropyButton.Tooltip = ["Calculates spectral entropy of NIRS or selected component of MREG or EEG from signal 1 (in 30 second time windows)" ...
                                                "!SELECT ONLY NIRS OR MREG/EEG COMPONENT AND USE 'Signal 1' VALUE!"];
            app.PlotpowerspectrumButton.Tooltip = "Plots power spectrum of Signal 1"; 


            %{
            % Returns the folder where the compiled executable actually resides.
            GetExecutableFolder;
            %got this function from 
            %https://se.mathworks.com/matlabcentral/answers/183005-changing-the-start-folder-for-compiled-app-shortcut-matlab-2014a#answer_171237

            function [executableFolder] = GetExecutableFolder() 
	            try
		            if isdeployed 
			            % User is running an executable in standalone mode. 
			            [status, result] = system('set PATH');
			            executableFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                        fprintf(1, '\nIn function GetExecutableFolder(), currentWorkingDirectory = %s\n', executableFolder);
		            else
			            % User is running an m-file from the MATLAB integrated development environment (regular MATLAB).
			            executableFolder = pwd; 
		            end 
	            catch ME
		            errorMessage = sprintf('Error in function %s() at line %d.\n\nError Message:\n%s', ...
			            ME.stack(1).name, ME.stack(1).line, ME.message);
		            uiwait(warndlg(errorMessage));
              end
            end
            %}
        end

        % Button pushed function: SelectdataButton
        function SelectdataButtonPushed(app, event)
            [app.file,path] = uigetfile('*.txt');
            if isequal(app.file,0)
                disp('User selected cancel');               %user aborted the file selection
            else 
                disp(['User selected', fullfile(path,app.file)]);
                app.CurrentdataEditField.Value = app.file;      %show working data folder in app front view
                app.Plot1Button.Enable = "on";
                app.filterbuttonclicked = "false";
                
                %if file is mreg.txt ask for component value and take the   
                %data out from app
                if app.file == "mreg.txt"
                    app.AskMregComponentApp = AskMregComponent(app);    %opens another app where component value asked  
                    waitfor(app.AskMregComponentApp);                   %wait for another window/app to close
                    %if user selected cancel, exit the sub app and go main
                    %to app
                    if isequal(app.component,0)
                        disp("User selected cancel");
                    else
                        app.data = importdata(app.file);                        %import the file
                        app.data = app.data(:,app.component);                  %take the component out of te file and store to data
                        %enables import buttons on signal calculations
                        app.Import1Button.Enable = 'on';
                        
                    end
                %if file is eeg.txt ask for component value and take the data out from app                                           
                elseif app.file == "eeg.txt"
                    app.AskEegComponentApp = AskEegComponent(app);
                    waitfor(app.AskEegComponentApp);
                    %if user selected cancel, go back to main app
                    if isequal(app.component,0)
                        disp("User selected cancel");
                    else
                        app.data = importdata(app.file);                        %import the file
                        app.data = app.data(:,app.component);                  %take the component out of te file and store to data
                        %enables import buttons on signal calculations
                        app.Import1Button.Enable = 'on';
                       
                    end
                else
                    app.data = importdata(app.file);                        %import the file to readable data
                    %enables import buttons on signal calculations
                    app.Import1Button.Enable = 'on';
                    app.ComponentEditField.Value = 0;
                end
            end         
        end

        % Button pushed function: Plot1Button
        function Plot1ButtonPushed(app, event)
            
            if app.p13 == 1      %OPEN UI WHERE YOU CAN OPEN ANOTHER SIGNAL TO PLOT WHEN PRESSING MORE BUTTON
                [file3,path1] = uigetfile('*.txt');
                if isequal(file3,0)
                    disp('User selected cancel');               %user aborted the file selection
                else 
                    disp(['User selected', fullfile(path1,file3)]); %user selected file and can continue
                    
                    %if file is mreg.txt ask for component value and take the   
                    %data out from app
                    if file3 == "mreg.txt"
                        app.AskMregComponentApp = AskMregComponent(app);    %opens another app where component value asked  
                        waitfor(app.AskMregComponentApp);                   %wait for another window/app to close
                        %if user selected cancel, go back to main app
                        if isequal(app.component,0)
                            disp("User selected cancel");
                        else
                            data1 = importdata(file3);                        %import the file
                            data1 = data1(:,app.component);                  %take the component out of te file and store to data
                            %show spatial map of the component (mreg)
                            componentvalueString = num2str(app.component);     %convert component value to string
                            pic = ['MREG_components\MREG_IC_', componentvalueString, '.png'];        %make a file name                             
                            pic = imread(pic);                                  %read the picture
                            figure;
                            imshow(pic);                                        %plot the picture in a figure
                            %plot data in figure
                            app.f1 = figure('Name','Data 1');
                            figure(app.f1);
                            plot(data1, "DisplayName", "MREG of component No." + num2str(app.component));
                            xlabel('Sample number');
                            ylabel('Amplitude');
                            legend;
                            app.Plot2Button.Enable = "on";
                        end


                    %if file is eeg.txt ask for component value and take the data out from app                                           
                    elseif file3 == "eeg.txt"
                        app.AskEegComponentApp = AskEegComponent(app);
                        waitfor(app.AskEegComponentApp);
                        %if user selected cancel, go back to main app
                        if isequal(app.component,0)
                            disp("User selected cancel");
                        else
                            data1 = importdata(file3);                        %import the file
                            data1 = data1(:,app.component);                  %take the component out of te file and store to data
                            %plot data in figure
                            app.f1 = figure('Name','Data 1');
                            figure(app.f1);
                            plot(data1, "DisplayName", "EEG of component No." + num2str(app.component));
                            xlabel('Sample number');
                            ylabel('Amplitude');
                            legend;
                            app.Plot2Button.Enable = "on";
                        end
                    else
                        data1 = importdata(file3);                        %import the file to readable data
                        %plot data in figure
                        app.f1 = figure('Name','Data 1');
                        figure(app.f1)
                        plot(data1,"DisplayName", file3);
                        xlabel('Sample number');
                        ylabel('Amplitude');
                        legend;
                        app.Plot2Button.Enable = "on";
                    end    
                end
                
            elseif app.p12 == 1      %PLOT FILTERED SIGNAL
                %if file is mreg.txt
                if app.CurrentdataEditField.Value == "mreg.txt"
                    %show spatial map of the component (mreg)
                    componentvalueString = num2str(app.component);     %convert component value to string
                    pic = ['MREG_components\MREG_IC_', componentvalueString, '.png'];        %make a file name                             
                    pic = imread(pic);                                  %read the picture
                    figure;
                    imshow(pic);                                        %plot the picture in a figure
                    %plot data in figure
                    app.f1 = figure('Name','Data 1');
                    figure(app.f1);
                    plot(app.FilteredData, "DisplayName", "Filtered MREG of component No." + num2str(app.component));
                    xlabel('Sample number');
                    ylabel('Amplitude');
                    legend;
                    app.Plot2Button.Enable = "on";

                 %if file is eeg
                elseif app.CurrentdataEditField.Value == "eeg.txt"
                     %plot data in figure
                     app.f1 = figure('Name','Data 1');
                     figure(app.f1);
                     plot(app.FilteredData, "DisplayName", "Filtered EEG of component No." + num2str(app.component));
                     xlabel('Sample number');
                     ylabel('Amplitude');
                     legend;
                     app.Plot2Button.Enable = "on";

                else
                    %plot data in figure
                    app.f1 = figure('Name','Data 1');
                    figure(app.f1);
                    plot(app.FilteredData, "DisplayName", "filtered" + app.CurrentdataEditField.Value);
                    xlabel('Sample number');
                    ylabel('Amplitude');
                    legend;
                    app.Plot2Button.Enable = "on";

                end


            else       %PLOT ORIGINAL SIGNAL
                %if mreg data selected
                if app.CurrentdataEditField.Value == "mreg.txt"
                    %show spatial map of the component (mreg)
                    componentvalueString = num2str(app.component);     %convert component value to string
                    pic = ['MREG_components\MREG_IC_', componentvalueString, '.png'];        %make a file name                             
                    pic = imread(pic);                                  %read the picture
                    figure;
                    imshow(pic);                                        %plot the picture in a figure
                    %plot data in figure
                    app.f1 = figure('Name','Data 1'); 
                    figure(app.f1);
                    plot(app.data, "DisplayName", "MREG of component No." + num2str(app.component));
                    xlabel('Sample number');
                    ylabel('Amplitude');
                    legend;
                    app.Plot2Button.Enable = "on";
                                        
                elseif app.CurrentdataEditField.Value == "eeg.txt"
                    %plot data in figure
                    app.f1 = figure('Name','Data 1');        
                    figure(app.f1) 
                    plot(app.data, "DisplayName","EEG of component No. " + num2str(app.component));
                    xlabel('Sample number');
                    ylabel('Amplitude');
                    legend;  
                    app.Plot2Button.Enable = "on";
                                         
                else
                    %plot data in figure
                    app.f1 = figure('Name','Data 1');
                    figure(app.f1);
                    plot(app.data, "DisplayName", app.CurrentdataEditField.Value);
                    xlabel('Sample number');
                    ylabel('Amplitude');
                    legend;
                    app.Plot2Button.Enable = "on";
                end
            end          
        end

        % Selection changed function: SelectfiltertypeButtonGroup
        function SelectfiltertypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectfiltertypeButtonGroup.SelectedObject;
            
            app.v1 = app.HighpassfilteringButton.Value;
            app.v2 = app.LowpassfilteringButton.Value;
            app.v3 = app.BandpassfilteringButton.Value;
            if app.v1 == 1                                                      %if press highpass filtering button then..
                app.HighfrequencyEditField.Value = 0;              %lower passband freq uneditable and zero
                app.HighfrequencyEditField.Editable = 'off';       
                app.LowfrequencyEditField.Editable = 'on';       %you can edit only the higher passband
                app.FilterButton.Enable = 'on';
            elseif app.v2 == 1                                                  %if press lowpass filtering button..
                app.LowfrequencyEditField.Value = 0;             %higher passband freq uneditable and zero
                app.LowfrequencyEditField.Editable = 'off';      %
                app.HighfrequencyEditField.Editable = 'on';        %you can edit only the lower passband
                app.FilterButton.Enable = 'on';
            elseif app.v3 == 1                                                  %if press band pass button..
                app.LowfrequencyEditField.Editable = 'on';       %both lower and higher is editable
                app.HighfrequencyEditField.Editable = 'on';        %
                app.FilterButton.Enable = 'on';
            end
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            
            inputhpbf = app.LowfrequencyEditField.Value; %high pass frequency input
            inputlpbf = app.HighfrequencyEditField.Value;  %low pass frequency input
            
            app.FilteredData = y_IdealFilter(app.data,app.samplep,[inputhpbf inputlpbf]);        %use y_IdealFilter to filter the data
            %use flag
            app.filterbuttonclicked = "true";
            %enables import buttons on signal calculations
            app.Import1Button.Enable = 'on';
            app.Import2Button.Enable = 'on';
            app.FilterButton.Enable = 'off';
            app.SavefiltereddataButton.Enable = 'on';               %when pressing filterbutton, save data button is now usable     
        end

        % Button pushed function: SavefiltereddataButton
        function SavefiltereddataButtonPushed(app, event)
            %makes new .txt file of filtered signal
            [filename, folder, indx] = uiputfile("*.txt");
            if isequal(indx,0)
                disp('User selected cancel when saving the file');               %user aborted the file selection
            else
                writematrix(app.FilteredData, fullfile(folder,filename))
            end
        end

        % Button pushed function: Plot2Button
        function Plot2ButtonPushed(app, event)
            if app.p23 == 1      %IF MORE: OPEN UI WHERE YOU CAN PICK ANOTHER SIGAL TO PLOT
                [file2,path2] = uigetfile('*.txt');
                if isequal(file2,0)
                    disp('User selected cancel');               %user aborted the file selection
                else 
                    disp(['User selected', fullfile(path2,file2)]);
                    %if file is mreg.txt ask for component value and take the   
                    %data out from app
                    if file2 == "mreg.txt"
                        app.AskMregComponentApp = AskMregComponent(app);    %opens another app where component value asked  
                        waitfor(app.AskMregComponentApp);                   %wait for another window/app to close
                        %if user selected cancel, go back to main app
                        if isequal(app.component,0)
                            disp("User selected cancel");
                        else
                            data2 = importdata(file2);                        %import the file
                            data2 = data2(:,app.component);                  %take the component out of te file and store to data
                            %plot data in figure
                            hold on;
                            figure(app.f1);
                            plot(data2,"DisplayName","MREG of component No."+ num2str(app.component));
                            xlabel('Sample number');
                            ylabel('Amplitude');
                            legend;
                            hold off;
                            %show spatial map of the component (mreg)
                            componentvalueString = num2str(app.component);     %convert component value to string
                            pic = ['MREG_components\MREG_IC_', componentvalueString, '.png'];        %make a file name                             
                            pic = imread(pic);                                  %read the picture
                            figure;
                            imshow(pic);                                        %plot the picture in a figure
                        end
                                                              
                    %if file is eeg.txt ask for component value and take the data out from app                                           
                    elseif file2 == "eeg.txt"
                        app.AskEegComponentApp = AskEegComponent(app);
                        waitfor(app.AskEegComponentApp);
                        %if user selected cancel, go back to main app
                        if isequal(app.component,0)
                            disp("User selected cancel");
                        else
                            data2 = importdata(file2);                        %import the file
                            data2 = data2(:,app.component);                  %take the component out of te file and store to data
                            %plot data in figure
                            hold on;
                            figure(app.f1);
                            plot(data2,"DisplayName","EEG of component No."+ num2str(app.component));
                            xlabel('Sample number');
                            ylabel('Amplitude');
                            legend;
                            hold off;   
                        end

                    else
                        data2 = importdata(file2);                        %import the file to readable data
                        %plot data in figure
                        hold on;
                        figure(app.f1);
                        plot(data2,"DisplayName",file2);
                        xlabel('Sample number');
                        ylabel('Amplitude');
                        legend;
                        hold off;
                    end
                end
                
            elseif app.p22 == 1      %PLOT FILTERED SIGNAL
                %if file is mreg.txt
                if app.CurrentdataEditField.Value == "mreg.txt"
                    %plot data in figure
                    hold on;
                    figure(app.f1);
                    plot(app.FilteredData, "DisplayName","Filtered MREG of component No."+ num2str(app.component));
                    xlabel('Sample number');
                    ylabel('Amplitude');
                    legend;
                    hold off;
                    %show spatial map of the component (mreg)
                    componentvalueString = num2str(app.component);     %convert component value to string
                    pic = ['MREG_components\MREG_IC_', componentvalueString, '.png'];        %make a file name                             
                    pic = imread(pic);                                  %read the picture
                    figure;
                    imshow(pic);                                        %plot the picture in a figure
                    
                 %if file is eeg
                elseif app.CurrentdataEditField.Value == "eeg.txt"
                     %plot data in figure
                     hold on;
                     figure(app.f1);
                     plot(app.FilteredData, "DisplayName","Filtered EEG of component No."+ num2str(app.component));
                     xlabel('Sample number');
                     ylabel('Amplitude');
                     legend;
                     hold off;

                else
                    %plot data in figure
                    hold on;
                    figure(app.f1);
                    plot(app.FilteredData, "DisplayName", "Filtered " + app.CurrentdataEditField.Value);
                    xlabel('Sample number');
                    ylabel('Amplitude');
                    legend;
                    hold off;
                end

            else     
            %plot original signal
                %if mreg data selected
                if app.CurrentdataEditField.Value == "mreg.txt"
                    %plot data in figure
                    hold on;
                    figure(app.f1);
                    plot(app.data, "DisplayName","MREG of component No."+ num2str(app.component));
                    xlabel('Sample number');
                    ylabel('Amplitude');
                    legend;
                    hold off;
                    %show spatial map of the component (mreg)
                    componentvalueString = num2str(app.component);     %convert component value to string
                    pic = ['MREG_components\MREG_IC_', componentvalueString, '.png'];        %make a file name                             
                    pic = imread(pic);                                  %read the picture
                    figure;
                    imshow(pic);                                        %plot the picture in a figure
                    
                elseif app.CurrentdataEditField.Value == "eeg.txt"
                    %plot data in figure
                    hold on;
                    figure(app.f1);
                    plot(app.data, "DisplayName","EEG of component No."+ num2str(app.component));
                    xlabel('Sample number');
                    ylabel('Amplitude');
                    legend;
                    hold off;
                              
                else
                    %plot data in figure
                    hold on;
                    figure(app.f1);
                    plot(app.data, "DisplayName",app.CurrentdataEditField.Value);
                    legend
                    hold off;
                end

            end
        end

        % Selection changed function: Plotfordata1ButtonGroup
        function Plotfordata1ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Plotfordata1ButtonGroup.SelectedObject;
            app.p11 = app.OriginalsignalButton.Value;
            app.p12 = app.FilteredsignalButton.Value;
            app.p13 = app.MoreButton.Value;
        end

        % Selection changed function: Plotmanyonsamedata1ButtonGroup
        function Plotmanyonsamedata1ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Plotmanyonsamedata1ButtonGroup.SelectedObject;
            
            app.p21 = app.OriginalsignalButton_2.Value;
            app.p22 = app.FilteredsignalButton_2.Value;
            app.p23 = app.MoreButton_2.Value;

            if app.p21 == 1
                app.Plot2Button.Enable = "on";      %if original signal button pressed, then plot 2 button enable

            elseif app.p22 == 1
                app.Plot2Button.Enable = "on";      %if filtered signal button pressed, then plot 2 button enable

            elseif app.p23 == 1
                app.Plot2Button.Enable = "on";      %if more button pressed, then plot 2 button enable

            end
        end

        % Value changed function: ComponentEditField
        function ComponentEditFieldValueChanged(app, event)
            value = app.ComponentEditField.Value;         
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %close request callback, if user press cancel or X and want to
            %leave the app this question confirms that user want to leave
            YN = uiconfirm(app.UIFigure,'Do you want to close the app?', 'Close request');   
            if strcmpi(YN,'OK')
                delete(app)
            end
        end

        % Selection changed function: Importforsignal1ButtonGroup
        function Importforsignal1ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Importforsignal1ButtonGroup.SelectedObject;
            %take the values of radio buttons and store to variables
            app.currentdata1 = app.CurrentdataButton.Value;
            app.filtereddata1 = app.FiltereddataButton.Value;
        end

        % Selection changed function: Importforsignal2ButtonGroup
        function Importforsignal2ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Importforsignal2ButtonGroup.SelectedObject;
            %take the values of radio buttons and store to variables
            app.currentdata2 = app.CurrentdataButton_2.Value;
            app.filtereddata2 = app.FiltereddataButton_2.Value;
        end

        % Button pushed function: Import1Button
        function Import1ButtonPushed(app, event)
            %if filtereddata radiobutton on
            if app.filtereddata1 == 1
                %check that filteredbutton is clicked
                if app.filterbuttonclicked == "true"
                    %if data is mreg.txt or eeg.txt show also which component
                    if app.CurrentdataEditField.Value == "mreg.txt" || app.CurrentdataEditField.Value == "eeg.txt"
                        app.Signal1EditField.Value = "Filtered " + app.CurrentdataEditField.Value;
                        %store already processed filtered data to signal 1
                        app.signal1 = app.FilteredData;
                        app.ComponentEditField_2.Value = app.component;
                        %CV, spectral entropy and plot powerspectrum enable
                        app.CoefficientofvarianceButton.Enable = "on";
                        app.PlotspectralentropyButton.Enable = "on";
                        app.PlotpowerspectrumButton.Enable = "on";
                        %enable import 2 button
                        app.Import2Button.Enable = "on";
          
                    else
                        app.Signal1EditField.Value = "Filtered " + app.CurrentdataEditField.Value;
                        %store already processed filtered data to signal 1
                        app.signal1 = app.FilteredData;
                        app.ComponentEditField_2.Value = 0;
                        %CV, spectral entropy and plot powerspectrum enable
                        app.CoefficientofvarianceButton.Enable = "on";
                        app.PlotspectralentropyButton.Enable = "on";
                        app.PlotpowerspectrumButton.Enable = "on";
                        %enable import 2 button
                        app.Import2Button.Enable = "on";
                    end
                end


             %if currentdata radiobtutton on
            else
                %if data is mreg.txt or eeg.txt show also which component
                if app.CurrentdataEditField.Value == "mreg.txt" || app.CurrentdataEditField.Value == "eeg.txt"
                    app.Signal1EditField.Value = app.CurrentdataEditField.Value;
                    %store already processed data to signal 1
                    app.signal1 = app.data;
                    app.ComponentEditField_2.Value = app.component;
                    %CV, spectral entropy and plot powerspectrum enable
                    app.CoefficientofvarianceButton.Enable = "on";
                    app.PlotspectralentropyButton.Enable = "on";
                    app.PlotpowerspectrumButton.Enable = "on";
                    %enable import 2 button
                    app.Import2Button.Enable = "on";
                else
                    app.Signal1EditField.Value = app.CurrentdataEditField.Value;
                     %store already processed data to signal 1
                    app.signal1 = app.data;
                    app.ComponentEditField_2.Value = 0;
                    %CV, spectral entropy and plot powerspectrum enable
                    app.CoefficientofvarianceButton.Enable = "on";
                    app.PlotspectralentropyButton.Enable = "on";
                    app.PlotpowerspectrumButton.Enable = "on";
                    %enable import 2 button
                    app.Import2Button.Enable = "on";
                end
            end
        end

        % Button pushed function: Import2Button
        function Import2ButtonPushed(app, event)
            %if filtereddata radiobutton on
            if app.filtereddata2 == 1
                if app.filterbuttonclicked == "true"
                    %if data is mreg.txt or eeg.txt show also which component
                    if app.CurrentdataEditField.Value == "mreg.txt" || app.CurrentdataEditField.Value == "eeg.txt"
                        app.Signal2EditField.Value = "Filtered " + app.CurrentdataEditField.Value;
                        %store already processed filtered data to signal 2
                        app.signal2 = app.FilteredData;
                        app.ComponentEditField_3.Value = app.component;
                        %enable correcoef button and cross corre button
                        app.CorrelationcoefficientButton.Enable = "on";
                        app.MaxcrosscorrelationButton.Enable = "on";
                    else
                        app.Signal2EditField.Value = "Filtered " + app.CurrentdataEditField.Value;
                        %store already processed filtered data to signal 2
                        app.signal2 = app.FilteredData;
                        app.ComponentEditField_3.Value = 0;
                        %enable correcoef button and cross corre button
                        app.CorrelationcoefficientButton.Enable = "on";
                        app.MaxcrosscorrelationButton.Enable = "on";
                    end
                end

            %if currentdata radiobtutton on
            else
                %if data is mreg.txt or eeg.txt show also which component
                if app.CurrentdataEditField.Value == "mreg.txt" || app.CurrentdataEditField.Value == "eeg.txt"
                    app.Signal2EditField.Value = app.CurrentdataEditField.Value;
                    %store already processed data to signal 2
                    app.signal2 = app.data;
                    app.ComponentEditField_3.Value = app.component;
                    %enable correcoef button and cross corre button
                    app.CorrelationcoefficientButton.Enable = "on";
                    app.MaxcrosscorrelationButton.Enable = "on";
                else
                    app.Signal2EditField.Value = app.CurrentdataEditField.Value;
                    %store already processed data to signal 2
                    app.signal2 = app.data;
                    app.ComponentEditField_3.Value = 0;
                    %enable correcoef button and cross corre button
                    app.CorrelationcoefficientButton.Enable = "on";
                    app.MaxcrosscorrelationButton.Enable = "on";
                end
            end
        end

        % Button pushed function: CorrelationcoefficientButton
        function CorrelationcoefficientButtonPushed(app, event)
            %calculates the correlation coefficient value of signal 1 and
            %signal 2 and shows the value in edit field
            app.correcoeffi = corr2(app.signal1, app.signal2);
            app.CorreEditField.Value = app.correcoeffi;            
        end

        % Button pushed function: MaxcrosscorrelationButton
        function MaxcrosscorrelationButtonPushed(app, event)
            %check that both imported signals are filtered
            if contains(app.Signal1EditField.Value,"Filtered") == 1 && contains(app.Signal2EditField.Value, "Filtered") == 1
                %calculate cross correlation
                [val, timedifference] = calculateXCORR(app,app.signal1, app.signal2);
                %convert numeric to strings
                valstr = num2str(val);
                timedifferencestr = num2str(timedifference);
                %show in edit field
                app.CrossCorreEditField.Value = [valstr + " | " + timedifferencestr + "s"];
            %check if imported signal 1 is not filtered, remove the mean from that.    
            elseif contains(app.Signal1EditField.Value,"Filtered") == 0 && contains(app.Signal2EditField.Value, "Filtered") == 1
                %remove mean of the signal
                signal1_d = detrend(app.signal1);
                %calculate cross correlation
                [val, timedifference] = calculateXCORR(app, signal1_d, app.signal2);
                %convert numeric to strings
                valstr = num2str(val);
                timedifferencestr = num2str(timedifference);
                %show in edit field
                app.CrossCorreEditField.Value = [valstr + " | " + timedifferencestr + "s"];
            %check if imported signal 2 is not filtered, remove that mean
            elseif contains(app.Signal1EditField.Value,"Filtered") == 1 && contains(app.Signal2EditField.Value, "Filtered") == 0
                %remove mean of the signal
                signal2_d = detrend(app.signal2);
                %calculate cross correlation
                [val, timedifference] = calculateXCORR(app, app.signal1, signal2_d);
                %convert numeric to strings
                valstr = num2str(val);
                timedifferencestr = num2str(timedifference);
                %show in edit field
                app.CrossCorreEditField.Value = [valstr + " | " + timedifferencestr + "s"];
            %check if both signals are not filtered, remove means of both
            else
                %remove mean of the signal 1
                signal1_d = detrend(app.signal1);
                %remove mean of the signal 2
                signal2_d = detrend(app.signal2);
                %calculate cross correlation
                [val, timedifference] = calculateXCORR(app, signal1_d, signal2_d);
                %convert numeric to strings
                valstr = num2str(val);
                timedifferencestr = num2str(timedifference);
                %show in edit field
                app.CrossCorreEditField.Value = [valstr + " | " + timedifferencestr + "s"];
            end
        end

        % Button pushed function: CoefficientofvarianceButton
        function CoefficientofvarianceButtonPushed(app, event)
            %check that imported signal 1 is not filtered
            if contains(app.Signal1EditField.Value,"Filtered") == 0
                %calculates the coefficient of variance
                cv = std(app.signal1)/mean(app.signal1);
                cv = round(cv,7);
                app.CoeffiVarianceEditField.Value = cv;
            end
        end

        % Button pushed function: PlotspectralentropyButton
        function PlotspectralentropyButtonPushed(app, event)
            %check if imported signal 1 is eeg, mreg or nirs signal
            if contains(app.Signal1EditField.Value,"eeg.txt") == 1 || contains(app.Signal1EditField.Value,"mreg.txt") == 1 || contains(app.Signal1EditField.Value,"nirs.txt") == 1
                %check that imported signal is filtered
                if contains(app.Signal1EditField.Value,"Filtered") == 1
                    fs=291/30;
                    pentropi = pentropy(app.signal1,fs);
                    figure;
                    plot(pentropi);
                 %check if imported signal 1 is not filtered, remove the mean from that.    
                elseif contains(app.Signal1EditField.Value,"Filtered") == 0
                    fs=291/30;
                    %remove mean of signal
                    signal1_d = detrend(app.signal1);
                    pentropi = pentropy(signal1_d,fs);
                    figure;
                    plot(pentropi);
                end
            end
        end

        % Button pushed function: PlotpowerspectrumButton
        function PlotpowerspectrumButtonPushed(app, event)
            %if import signal 1 contains data then continue
            if contains(app.Signal1EditField.Value,".txt") == 1
                %check that imported signal is filtered
                if contains(app.Signal1EditField.Value,"Filtered") == 1
                    %compute the power spectrum
                    [fr, powerspec] = calcpowerspectrum(app,app.signal1);
                    %plot power spectrum with xlimit 0-5hz
                    figure;
                    plot(fr,powerspec);
                    xlabel('Frequency (Hz)');
                    xlim([0 5]);
                    ylabel('Power Spectrum (dB)'); 
                %check if imported signal 1 is not filtered, remove the mean from that.    
                elseif contains(app.Signal1EditField.Value,"Filtered") == 0
                    %remove mean of signal
                    signal1_d = detrend(app.signal1);
                    %compute the power spectrum
                    [fr, powerspec] = calcpowerspectrum(app,signal1_d);
                    %plot power spectrum with xlimit 0-5hz
                    figure;
                    plot(fr,powerspec);
                    xlabel('Frequency (Hz)');
                    xlim([0 5]);
                    ylabel('Power Spectrum (dB)');  
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {256, '1x', 412};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectdataButton = uibutton(app.LeftPanel, 'push');
            app.SelectdataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdataButtonPushed, true);
            app.SelectdataButton.Text = 'Select data';
            app.CurrentdataEditFieldLabel = uilabel(app.LeftPanel);
            app.CurrentdataEditFieldLabel.Text = 'Current data';
            app.CurrentdataEditField = uieditfield(app.LeftPanel, 'text');
            app.CurrentdataEditField.Editable = 'off';
            app.HighfrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.HighfrequencyEditFieldLabel.Text = 'High frequency';
            app.HighfrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LowfrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.LowfrequencyEditFieldLabel.Text = 'Low frequency';
            app.LowfrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FilterButton = uibutton(app.LeftPanel, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Enable = 'off';
            app.FilterButton.Text = 'Filter';
            app.SelectfiltertypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.SelectfiltertypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectfiltertypeButtonGroupSelectionChanged, true);
            app.SelectfiltertypeButtonGroup.Title = 'Select filter type';
            app.BandpassfilteringButton = uiradiobutton(app.SelectfiltertypeButtonGroup);
            app.BandpassfilteringButton.Text = 'Band pass filtering';
            app.LowpassfilteringButton = uiradiobutton(app.SelectfiltertypeButtonGroup);
            app.LowpassfilteringButton.Text = 'Low pass filtering';
            app.LowpassfilteringButton.Value = true;
            app.HighpassfilteringButton = uiradiobutton(app.SelectfiltertypeButtonGroup);
            app.HighpassfilteringButton.Text = 'High pass filtering';
            app.SavefiltereddataButton = uibutton(app.LeftPanel, 'push');
            app.SavefiltereddataButton.ButtonPushedFcn = createCallbackFcn(app, @SavefiltereddataButtonPushed, true);
            app.SavefiltereddataButton.Enable = 'off';
            app.SavefiltereddataButton.Text = 'Save filtered data';
            app.ComponentEditFieldLabel = uilabel(app.LeftPanel);
            app.ComponentEditFieldLabel.Text = 'Component';
            app.ComponentEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ComponentEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentEditFieldValueChanged, true);
            app.ComponentEditField.Editable = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.Plot1Button = uibutton(app.CenterPanel, 'push');
            app.Plot1Button.ButtonPushedFcn = createCallbackFcn(app, @Plot1ButtonPushed, true);
            app.Plot1Button.Enable = 'off';
            app.Plot1Button.Text = ' Plot 1';
            app.Plot2Button = uibutton(app.CenterPanel, 'push');
            app.Plot2Button.ButtonPushedFcn = createCallbackFcn(app, @Plot2ButtonPushed, true);
            app.Plot2Button.Enable = 'off';
            app.Plot2Button.Tooltip = {'Plots more signals in same figure '};
            app.Plot2Button.Text = 'Plot 2';
            app.Plotfordata1ButtonGroup = uibuttongroup(app.CenterPanel);
            app.Plotfordata1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Plotfordata1ButtonGroupSelectionChanged, true);
            app.Plotfordata1ButtonGroup.Title = 'Plot for data 1';
            app.OriginalsignalButton = uiradiobutton(app.Plotfordata1ButtonGroup);
            app.OriginalsignalButton.Text = 'Original signal';
            app.OriginalsignalButton.Value = true;
            app.FilteredsignalButton = uiradiobutton(app.Plotfordata1ButtonGroup);
            app.FilteredsignalButton.Text = 'Filtered signal';
            app.MoreButton = uiradiobutton(app.Plotfordata1ButtonGroup);
            app.MoreButton.Text = 'More..';
            app.Plotmanyonsamedata1ButtonGroup = uibuttongroup(app.CenterPanel);
            app.Plotmanyonsamedata1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Plotmanyonsamedata1ButtonGroupSelectionChanged, true);
            app.Plotmanyonsamedata1ButtonGroup.Title = 'Plot many on same data 1';
            app.OriginalsignalButton_2 = uiradiobutton(app.Plotmanyonsamedata1ButtonGroup);
            app.OriginalsignalButton_2.Text = 'Original signal';
            app.OriginalsignalButton_2.Value = true;
            app.FilteredsignalButton_2 = uiradiobutton(app.Plotmanyonsamedata1ButtonGroup);
            app.FilteredsignalButton_2.Text = 'Filtered signal';
            app.MoreButton_2 = uiradiobutton(app.Plotmanyonsamedata1ButtonGroup);
            app.MoreButton_2.Text = 'More..';
            app.RightPanel = uipanel(app.GridLayout);
            app.SignalcalculationsLabel = uilabel(app.RightPanel);
            app.SignalcalculationsLabel.Text = 'Signal calculations';
            app.Importforsignal1ButtonGroup = uibuttongroup(app.RightPanel);
            app.Importforsignal1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Importforsignal1ButtonGroupSelectionChanged, true);
            app.Importforsignal1ButtonGroup.Title = 'Import for signal 1';
            app.CurrentdataButton = uiradiobutton(app.Importforsignal1ButtonGroup);
            app.CurrentdataButton.Text = 'Current data';
            app.CurrentdataButton.Value = true;
            app.FiltereddataButton = uiradiobutton(app.Importforsignal1ButtonGroup);
            app.FiltereddataButton.Text = 'Filtered data';
            app.Importforsignal2ButtonGroup = uibuttongroup(app.RightPanel);
            app.Importforsignal2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Importforsignal2ButtonGroupSelectionChanged, true);
            app.Importforsignal2ButtonGroup.Title = 'Import for signal 2';
            app.CurrentdataButton_2 = uiradiobutton(app.Importforsignal2ButtonGroup);
            app.CurrentdataButton_2.Text = 'Current data';
            app.CurrentdataButton_2.Value = true;
            app.FiltereddataButton_2 = uiradiobutton(app.Importforsignal2ButtonGroup);
            app.FiltereddataButton_2.Text = 'Filtered data';
            app.Import1Button = uibutton(app.RightPanel, 'push');
            app.Import1Button.ButtonPushedFcn = createCallbackFcn(app, @Import1ButtonPushed, true);
            app.Import1Button.Enable = 'off';
            app.Import1Button.Text = 'Import 1';
            app.Import2Button = uibutton(app.RightPanel, 'push');
            app.Import2Button.ButtonPushedFcn = createCallbackFcn(app, @Import2ButtonPushed, true);
            app.Import2Button.Enable = 'off';
            app.Import2Button.Text = 'Import 2';
            app.CorrelationcoefficientButton = uibutton(app.RightPanel, 'push');
            app.CorrelationcoefficientButton.ButtonPushedFcn = createCallbackFcn(app, @CorrelationcoefficientButtonPushed, true);
            app.CorrelationcoefficientButton.Enable = 'off';
            app.CorrelationcoefficientButton.Text = 'Correlation coefficient';
            app.CorreEditField = uieditfield(app.RightPanel, 'numeric');
            app.CorreEditField.Editable = 'off';
            app.MaxcrosscorrelationButton = uibutton(app.RightPanel, 'push');
            app.MaxcrosscorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @MaxcrosscorrelationButtonPushed, true);
            app.MaxcrosscorrelationButton.Enable = 'off';
            app.MaxcrosscorrelationButton.Text = 'Max cross correlation';
            app.CoefficientofvarianceButton = uibutton(app.RightPanel, 'push');
            app.CoefficientofvarianceButton.ButtonPushedFcn = createCallbackFcn(app, @CoefficientofvarianceButtonPushed, true);
            app.CoefficientofvarianceButton.Enable = 'off';
            app.CoefficientofvarianceButton.Text = 'Coefficient of variance';
            app.CoeffiVarianceEditField = uieditfield(app.RightPanel, 'numeric');
            app.CoeffiVarianceEditField.Editable = 'off';
            app.PlotspectralentropyButton = uibutton(app.RightPanel, 'push');
            app.PlotspectralentropyButton.ButtonPushedFcn = createCallbackFcn(app, @PlotspectralentropyButtonPushed, true);
            app.PlotspectralentropyButton.Enable = 'off';
            app.PlotspectralentropyButton.Text = 'Plot spectral entropy';
            app.PlotpowerspectrumButton = uibutton(app.RightPanel, 'push');
            app.PlotpowerspectrumButton.ButtonPushedFcn = createCallbackFcn(app, @PlotpowerspectrumButtonPushed, true);
            app.PlotpowerspectrumButton.Enable = 'off';
            app.PlotpowerspectrumButton.Text = 'Plot power spectrum ';
            app.Signal1EditFieldLabel = uilabel(app.RightPanel);
            app.Signal1EditFieldLabel.Text = 'Signal 1';
            app.Signal1EditField = uieditfield(app.RightPanel, 'text');
            app.Signal1EditField.Editable = 'off';
            app.Signal2EditFieldLabel = uilabel(app.RightPanel);
            app.Signal2EditFieldLabel.Text = 'Signal 2';
            app.Signal2EditField = uieditfield(app.RightPanel, 'text');
            app.Signal2EditField.Editable = 'off';
            app.ComponentEditField_2Label = uilabel(app.RightPanel);
            app.ComponentEditField_2Label.Text = 'Component';
            app.ComponentEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.ComponentEditField_2.Editable = 'off';
            app.ComponentEditField_3Label = uilabel(app.RightPanel);
            app.ComponentEditField_3Label.Text = 'Component';
            app.ComponentEditField_3 = uieditfield(app.RightPanel, 'numeric');
            app.ComponentEditField_3.Editable = 'off';
            app.CrossCorreEditField = uieditfield(app.RightPanel, 'text');
            app.CrossCorreEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAappi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end